classdef combining_em_waves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ResetDisplayButton         matlab.ui.control.Button
        AppTitle                   matlab.ui.control.Label
        DisplayPanel               matlab.ui.container.Panel
        WaveOverlayCheckBox        matlab.ui.control.CheckBox
        WaveOverlayLabel           matlab.ui.control.Label
        DemoDropDown               matlab.ui.control.DropDown
        DemoDropDownLabel          matlab.ui.control.Label
        PerspectiveDropDown        matlab.ui.control.DropDown
        PerspectiveDropDownLabel   matlab.ui.control.Label
        DurationsecEditField       matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel  matlab.ui.control.Label
        Wave1Panel                 matlab.ui.container.Panel
        PolarizationDropDown       matlab.ui.control.DropDown
        FreqLabel                  matlab.ui.control.Label
        AmpLabel                   matlab.ui.control.Label
        AmplitudeSlider            matlab.ui.control.Slider
        FrequencySlider            matlab.ui.control.Slider
        FrequencySliderLabel       matlab.ui.control.Label
        AmplitudeSliderLabel       matlab.ui.control.Label
        Wave2Panel                 matlab.ui.container.Panel
        PolarizationDropDown_2     matlab.ui.control.DropDown
        PhaseLabel                 matlab.ui.control.Label
        FreqLabel_2                matlab.ui.control.Label
        AmpLabel_2                 matlab.ui.control.Label
        PhaseSliderLabel           matlab.ui.control.Label
        AmplitudeSlider_2          matlab.ui.control.Slider
        FrequencySlider_2          matlab.ui.control.Slider
        PhaseSlider                matlab.ui.control.Slider
        FrequencySlider_2Label     matlab.ui.control.Label
        AmplitudeSlider_2Label     matlab.ui.control.Label
        ResetWavesButton           matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes_Wave1               matlab.ui.control.UIAxes
        UIAxes_Wave2               matlab.ui.control.UIAxes
        UIAxes_Combined            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function updateplot(app)
            % Display
            samplePerCycle = 100;
            waveOverlay = app.WaveOverlayCheckBox.Value;
            duration = app.DurationsecEditField.Value;
            perspective = app.PerspectiveDropDown.Value;
        
            % Wave1
            polar1 = app.PolarizationDropDown.Value;
            fc1 = round(app.FrequencySlider.Value);  
            amp1 = round(app.AmplitudeSlider.Value); 
            fs1 = fc1 * samplePerCycle;
            t1 = 0:(1/fs1):(duration-1/fs1);    
            S1 = amp1 * sin(2*pi*fc1*t1);      
            if strcmp(polar1, "Horizontal")
                S1v = zeros(size(t1));
                S1h = S1;
            else
                S1h = zeros(size(t1));
                S1v = S1;
            end
            
            % 2d
            if strcmp(perspective, '2D')
                ylim(app.UIAxes_Wave1, [-10, 10]);
                if strcmp(polar1, "Horizontal")
                    plot3(app.UIAxes_Wave1, t1, zeros(size(t1)), S1, 'Color', 'red');  
                else
                    plot(app.UIAxes_Wave1, t1, S1, 'red');
                end
                view(app.UIAxes_Wave1, 2);
            % 3d
            elseif strcmp(perspective, '3D')
                ylim(app.UIAxes_Wave1, [-10, 10]);  
                zlim(app.UIAxes_Wave1, [-10, 10]);
                if strcmp(polar1, "Horizontal")
                    plot3(app.UIAxes_Wave1, t1, S1h, zeros(size(t1)), 'Color', 'red');
                else
                    plot3(app.UIAxes_Wave1, t1, zeros(size(t1)), S1v, 'Color', 'red');
                end
                view(app.UIAxes_Wave1, [1, -1, 1]);
            end
        
            % Wave2
            polar2 = app.PolarizationDropDown_2.Value;
            fc2 = round(app.FrequencySlider_2.Value);  
            amp2 = round(app.AmplitudeSlider_2.Value); 
            phase = app.PhaseSlider.Value;
            fs2 = fc2 * samplePerCycle;
            t2 = 0:(1/fs2):(duration-1/fs2);    
            S2 = amp2 * sin(2*pi*fc2*t2 + phase); 
            if strcmp(polar2, "Horizontal")
                S2v = zeros(size(t2));
                S2h = S2;
            else
                S2h = zeros(size(t2));
                S2v = S2;
            end
            
            % 2d
            if strcmp(perspective, '2D')
                ylim(app.UIAxes_Wave2, [-10, 10]);  
                if strcmp(polar2, "Horizontal")
                    plot3(app.UIAxes_Wave2, t2, zeros(size(t2)), S2, 'Color', '0.22,0.75,0.00');  
                else
                    plot(app.UIAxes_Wave2, t2, S2, 'Color', '0.22,0.75,0.00');
                end
                view(app.UIAxes_Wave2, 2);
            % 3d
            elseif strcmp(perspective, '3D')
                ylim(app.UIAxes_Wave2, [-10, 10]);  
                zlim(app.UIAxes_Wave2, [-10, 10]);
                if strcmp(polar2, "Horizontal")
                    plot3(app.UIAxes_Wave2, t2, S2h, zeros(size(t2)), 'Color', '0.22,0.75,0.00');
                else
                    plot3(app.UIAxes_Wave2, t2, zeros(size(t2)), S2v, 'Color', '0.22,0.75,0.00');
                end
                view(app.UIAxes_Wave2, [1, -1, 1]);
            end
        
            % Combined Wave
            % time sum needs to be of same size, ranging from 0->duration
            t_combined = linspace(0, duration, max(length(t1), length(t2)));

            % % old 2d combined signal, might be useful for other wave apps
            % S1_interp = interp1(t1, S1, t_combined, 'linear', 'extrap');
            % S2_interp = interp1(t2, S2, t_combined, 'linear', 'extrap');
            % S3 = (S1_interp + S2_interp);
   
            % new interpolated horizontal and vertical signals 1 & 2
            S1v_interp = interp1(t1, S1v, t_combined, 'linear', 'extrap');
            S1h_interp = interp1(t1, S1h, t_combined, 'linear', 'extrap');
            S2v_interp = interp1(t2, S2v, t_combined, 'linear', 'extrap');
            S2h_interp = interp1(t2, S2h, t_combined, 'linear', 'extrap');
            
            % Combined wave's vertical (Z) and horizontal (Y) axes names
            % are swapped to plot the 3d wave with proper orientation
            Z_combined = S1v_interp + S2v_interp;  % Vertical Amplitude (originally Y-axis)
            Y_combined = S1h_interp + S2h_interp;  % Horizontal Amplitude (originally Z-axis)

            if strcmp(perspective, '2D')
                ylim(app.UIAxes_Combined, [-20, 20]);
                plot3(app.UIAxes_Combined, t_combined, Z_combined, Y_combined, 'Color', 'blue'); 
                view(app.UIAxes_Combined, 2);
            elseif strcmp(perspective, '3D')
                ylim(app.UIAxes_Combined, [-10, 10]);  
                zlim(app.UIAxes_Combined, [-20, 20]);
                plot3(app.UIAxes_Combined, t_combined, Y_combined, Z_combined, 'Color', 'blue'); 
                view(app.UIAxes_Combined, [1, -1, 1]);
            end

            % If Wave Overlay checked then shows dashed lines on Combined graph for waves 1 & 2
            if waveOverlay == 1
                if strcmp(perspective, '2D')
                    hold(app.UIAxes_Combined, 'on');
                    plot(app.UIAxes_Combined, t_combined, S1v_interp, '--r', 'LineWidth', 0.49);  
                    plot(app.UIAxes_Combined, t_combined, S2v_interp, '--', 'Color', [0.22, 0.75, 0.00], 'LineWidth', 0.5); 
                    hold(app.UIAxes_Combined, 'off');
                elseif strcmp(perspective, '3D')
                    hold(app.UIAxes_Combined, 'on');
                    if strcmp(polar1, "Horizontal")
                        plot3(app.UIAxes_Combined, t_combined, S1h_interp, zeros(size(t_combined)), '--r', 'LineWidth', 0.49);
                    else
                        plot3(app.UIAxes_Combined, t_combined, zeros(size(t_combined)), S1v_interp, '--r', 'LineWidth', 0.49);
                    end
                    if strcmp(polar2, "Horizontal")
                        plot3(app.UIAxes_Combined, t_combined, S2h_interp, zeros(size(t_combined)), '--', 'Color', [0.22, 0.75, 0.00], 'LineWidth', 0.5); 
                    else
                        plot3(app.UIAxes_Combined, t_combined, zeros(size(t_combined)), S2v_interp, '--', 'Color', [0.22, 0.75, 0.00], 'LineWidth', 0.5); 
                    end
                    
                    hold(app.UIAxes_Combined, 'off');
                end
            end

        end
            
        function reset(app)
            app.PolarizationDropDown.ValueIndex = 1;
            app.PolarizationDropDown_2.ValueIndex = 1;
            app.AmplitudeSlider.Value = 5;
            app.AmplitudeSlider_2.Value = 5;
            app.FrequencySlider.Value = 4;
            app.FrequencySlider_2.Value = 4;
            app.PhaseSlider.Value = 0;
            app.DemoDropDown.ValueIndex = 1;

            resetLabels(app);
            updateplot(app);
        end

        function resetLabels(app)
            app.AmpLabel.Text = num2str(round(app.AmplitudeSlider.Value));
            app.FreqLabel.Text = num2str(round(app.FrequencySlider.Value));
            app.AmpLabel_2.Text = num2str(round(app.AmplitudeSlider_2.Value));
            app.FreqLabel_2.Text = num2str(round(app.FrequencySlider_2.Value));
            app.PhaseLabel.Text = num2str(app.PhaseSlider.Value);
        end

        function resetDisplay(app)
            app.WaveOverlayCheckBox.Value = 0;
            app.PerspectiveDropDown.ValueIndex = 1;
            app.DurationsecEditField.Value = 2;
            app.DemoDropDown.ValueIndex = 1;
          
            updateplot(app);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetLabels(app);
            updateplot(app)
        end

        % Value changed function: AmplitudeSlider
        function Amplitude1ValueChanged(app, event)
            app.AmpLabel.Text=num2str(round(app.AmplitudeSlider.Value));
            updateplot(app);
        end

        % Callback function
        function Wavelength1SliderChanged(app, event)

        end

        % Callback function
        function Wavelength1ValueChanged(app, event)
            
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)
            updateplot(app);            
        end

        % Value changed function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanged(app, event)
            app.AmpLabel_2.Text=num2str(round(app.AmplitudeSlider_2.Value));
            updateplot(app);
        end

        % Callback function
        function FrequencySlider_2ValueChanged(app, event)
            
        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            app.PhaseLabel.Text = num2str(round(app.PhaseSlider.Value, 2));
            updateplot(app);
        end

        % Value changed function: FrequencySlider
        function Freq1ValueChanged(app, event)
            app.FreqLabel.Text=num2str(round(app.FrequencySlider.Value));
            updateplot(app);
        end

        % Value changed function: FrequencySlider_2
        function Freq2ValueChanged(app, event)
            app.FreqLabel_2.Text=num2str(round(app.FrequencySlider_2.Value));
            updateplot(app);
        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AmpLabel.Text=num2str(round(changingValue));
            app.AmplitudeSlider.Value=round(changingValue);
            updateplot(app);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.FreqLabel.Text=num2str(round(changingValue));
            app.FrequencySlider.Value=round(changingValue);
            updateplot(app);
        end

        % Value changing function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.AmpLabel_2.Text=num2str(round(changingValue));
            app.AmplitudeSlider_2.Value=round(changingValue);
            updateplot(app);
        end

        % Value changing function: FrequencySlider_2
        function FrequencySlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.FreqLabel_2.Text=num2str(round(changingValue));
            app.FrequencySlider_2.Value=round(changingValue);
            updateplot(app);
        end

        % Value changing function: PhaseSlider
        function PhaseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PhaseLabel.Text=num2str(round(changingValue, 2));
            app.PhaseSlider.Value=changingValue;
            updateplot(app);
        end

        % Button pushed function: ResetWavesButton
        function ResetWavesButtonPushed(app, event)
            reset(app);
        end

        % Value changed function: PerspectiveDropDown
        function PerspectiveDropDownValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: PolarizationDropDown
        function PolarizationDropDownValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: PolarizationDropDown_2
        function PolarizationDropDown_2ValueChanged(app, event)
            updateplot(app);
        end

        % Button pushed function: ResetDisplayButton
        function ResetDisplayButtonPushed(app, event)
            resetDisplay(app)
        end

        % Value changed function: DemoDropDown
        function DemoDropDownValueChanged(app, event)
            demo = app.DemoDropDown.Value;
            
            if strcmp(demo, "None")
                
            elseif strcmp(demo, "Amplitude Modulation")
                app.PerspectiveDropDown.ValueIndex = 1;
                app.PolarizationDropDown.ValueIndex = 1;
                app.PolarizationDropDown_2.ValueIndex = 1;
                app.AmplitudeSlider.Value = 5;
                app.AmplitudeSlider_2.Value = 5;
                app.FrequencySlider.Value = 8;
                app.FrequencySlider_2.Value = 9;
                app.PhaseSlider.Value = 0;
            elseif strcmp(demo, "Constructive Interference")
                app.PerspectiveDropDown.ValueIndex = 1;
                app.PolarizationDropDown.ValueIndex = 1;
                app.PolarizationDropDown_2.ValueIndex = 1;
                app.AmplitudeSlider.Value = 10;
                app.AmplitudeSlider_2.Value = 10;
                app.FrequencySlider.Value = 4;
                app.FrequencySlider_2.Value = 4;
                app.PhaseSlider.Value = 0;
            elseif strcmp(demo, "Destructive Interference")
                app.PerspectiveDropDown.ValueIndex = 1;
                app.PolarizationDropDown.ValueIndex = 1;
                app.PolarizationDropDown_2.ValueIndex = 1;
                app.AmplitudeSlider.Value = 10;
                app.AmplitudeSlider_2.Value = 10;
                app.FrequencySlider.Value = 4;
                app.FrequencySlider_2.Value = 4;
                app.PhaseSlider.Value = 3.14;
            elseif strcmp(demo, "Circular Polarization")
                app.PerspectiveDropDown.ValueIndex = 2;
                app.PolarizationDropDown.ValueIndex = 1;
                app.PolarizationDropDown_2.ValueIndex = 2;
                app.AmplitudeSlider.Value = 10;
                app.AmplitudeSlider_2.Value = 2;
                app.FrequencySlider.Value = 4;
                app.FrequencySlider_2.Value = 4;
                app.PhaseSlider.Value = 1.57;
            end

            resetLabels(app);
            updateplot(app);
        end

        % Clicked callback: DemoDropDown
        function DemoDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            DemoDropDownValueChanged(app, event);
        end

        % Value changed function: WaveOverlayCheckBox
        function WaveOverlayCheckBoxValueChanged(app, event)
            updateplot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {690, 690};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {201, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResetWavesButton = uibutton(app.LeftPanel, 'push');
            app.ResetWavesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButtonPushed, true);
            app.ResetWavesButton.Text = 'Waves ↺';
            app.Wave2Panel = uipanel(app.LeftPanel);
            app.Wave2Panel.Title = 'Wave 2';
            app.AmplitudeSlider_2Label = uilabel(app.Wave2Panel);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.FrequencySlider_2Label = uilabel(app.Wave2Panel);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.PhaseSlider = uislider(app.Wave2Panel);
            app.PhaseSlider.Limits = [0 3.14159265358979];
            app.PhaseSlider.MajorTicks = [0 1.5707963267949 3.14159265358979];
            app.PhaseSlider.MajorTickLabels = {'0', 'π/2', 'π'};
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.PhaseSlider.MinorTicks = [0 0.195 0.39 0.585 0.78 0.975 1.17 1.366 1.755 1.95 2.145 2.34 2.535 2.73 2.925];
            app.FrequencySlider_2 = uislider(app.Wave2Panel);
            app.FrequencySlider_2.Limits = [1 9];
            app.FrequencySlider_2.MajorTicks = [1 3 5 7 9];
            app.FrequencySlider_2.ValueChangedFcn = createCallbackFcn(app, @Freq2ValueChanged, true);
            app.FrequencySlider_2.ValueChangingFcn = createCallbackFcn(app, @FrequencySlider_2ValueChanging, true);
            app.FrequencySlider_2.MinorTicks = [2 4 6 8];
            app.FrequencySlider_2.Value = 4;
            app.AmplitudeSlider_2 = uislider(app.Wave2Panel);
            app.AmplitudeSlider_2.Limits = [0 10];
            app.AmplitudeSlider_2.MajorTicks = [0 2 4 6 8 10];
            app.AmplitudeSlider_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanged, true);
            app.AmplitudeSlider_2.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanging, true);
            app.AmplitudeSlider_2.MinorTicks = [1 3 5 7 9];
            app.AmplitudeSlider_2.Value = 5;
            app.PhaseSliderLabel = uilabel(app.Wave2Panel);
            app.PhaseSliderLabel.Text = 'Phase';
            app.AmpLabel_2 = uilabel(app.Wave2Panel);
            app.AmpLabel_2.Text = 'Amp ';
            app.FreqLabel_2 = uilabel(app.Wave2Panel);
            app.FreqLabel_2.Text = 'Freq';
            app.PhaseLabel = uilabel(app.Wave2Panel);
            app.PhaseLabel.Text = 'Degrees';
            app.PolarizationDropDown_2 = uidropdown(app.Wave2Panel);
            app.PolarizationDropDown_2.Items = {'Vertical', 'Horizontal'};
            app.PolarizationDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PolarizationDropDown_2ValueChanged, true);
            app.PolarizationDropDown_2.Value = 'Vertical';
            app.Wave1Panel = uipanel(app.LeftPanel);
            app.Wave1Panel.Title = 'Wave 1';
            app.AmplitudeSliderLabel = uilabel(app.Wave1Panel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.FrequencySliderLabel = uilabel(app.Wave1Panel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.Wave1Panel);
            app.FrequencySlider.Limits = [1 9];
            app.FrequencySlider.MajorTicks = [1 3 5 7 9];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @Freq1ValueChanged, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.MinorTicks = [2 4 6 8];
            app.FrequencySlider.Value = 4;
            app.AmplitudeSlider = uislider(app.Wave1Panel);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @Amplitude1ValueChanged, true);
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider.MinorTicks = [1 3 5 7 9];
            app.AmplitudeSlider.Value = 5;
            app.AmpLabel = uilabel(app.Wave1Panel);
            app.AmpLabel.Text = 'Amp ';
            app.FreqLabel = uilabel(app.Wave1Panel);
            app.FreqLabel.Text = 'Freq';
            app.PolarizationDropDown = uidropdown(app.Wave1Panel);
            app.PolarizationDropDown.Items = {'Vertical', 'Horizontal'};
            app.PolarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarizationDropDownValueChanged, true);
            app.PolarizationDropDown.Value = 'Vertical';
            app.DisplayPanel = uipanel(app.LeftPanel);
            app.DisplayPanel.Title = 'Display';
            app.DurationsecEditFieldLabel = uilabel(app.DisplayPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DurationsecEditField.Limits = [1 60];
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.DurationsecEditField.Value = 2;
            app.PerspectiveDropDownLabel = uilabel(app.DisplayPanel);
            app.PerspectiveDropDownLabel.Text = 'Perspective';
            app.PerspectiveDropDown = uidropdown(app.DisplayPanel);
            app.PerspectiveDropDown.Items = {'2D', '3D'};
            app.PerspectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @PerspectiveDropDownValueChanged, true);
            app.PerspectiveDropDown.Value = '2D';
            app.DemoDropDownLabel = uilabel(app.DisplayPanel);
            app.DemoDropDownLabel.Text = 'Demo';
            app.DemoDropDown = uidropdown(app.DisplayPanel);
            app.DemoDropDown.Items = {'None', 'Amplitude Modulation', 'Constructive Interference', 'Destructive Interference', 'Circular Polarization'};
            app.DemoDropDown.ValueChangedFcn = createCallbackFcn(app, @DemoDropDownValueChanged, true);
            app.DemoDropDown.ClickedFcn = createCallbackFcn(app, @DemoDropDownClicked, true);
            app.DemoDropDown.Value = 'None';
            app.WaveOverlayLabel = uilabel(app.DisplayPanel);
            app.WaveOverlayLabel.Text = 'Wave Overlay';
            app.WaveOverlayCheckBox = uicheckbox(app.DisplayPanel);
            app.WaveOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaveOverlayCheckBoxValueChanged, true);
            app.WaveOverlayCheckBox.Text = '';
            app.AppTitle = uilabel(app.LeftPanel);
            app.AppTitle.Text = 'Combining EM Waves';
            app.ResetDisplayButton = uibutton(app.LeftPanel, 'push');
            app.ResetDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDisplayButtonPushed, true);
            app.ResetDisplayButton.Text = 'Display ↺';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_Combined = uiaxes(app.RightPanel);
            title(app.UIAxes_Combined, 'Wave 1 + Wave 2')
            xlabel(app.UIAxes_Combined, 'Time (sec)')
            ylabel(app.UIAxes_Combined, 'Amplitude (V)')
            zlabel(app.UIAxes_Combined, 'Amplitude (V)')
            app.UIAxes_Wave2 = uiaxes(app.RightPanel);
            title(app.UIAxes_Wave2, 'Wave 2')
            xlabel(app.UIAxes_Wave2, 'Time (sec)')
            ylabel(app.UIAxes_Wave2, 'Amplitude (V)')
            zlabel(app.UIAxes_Wave2, 'Amplitude (V)')
            app.UIAxes_Wave1 = uiaxes(app.RightPanel);
            title(app.UIAxes_Wave1, 'Wave 1')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            zlabel(app.UIAxes_Wave1, 'Amplitude (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combining_em_waves

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end