classdef medium < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResetDisplayButton           matlab.ui.control.Button
        ResetWavesButton_2           matlab.ui.control.Button
        Wave1Panel                   matlab.ui.container.Panel
        FreqLabel                    matlab.ui.control.Label
        AmpLabel                     matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        FrequencySliderLabel         matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        WaveMedium                   matlab.ui.container.Panel
        LenLabel                     matlab.ui.control.Label
        RefraLabel                   matlab.ui.control.Label
        ExtinLabel                   matlab.ui.control.Label
        LengthSliderLabel            matlab.ui.control.Label
        LengthSlider                 matlab.ui.control.Slider
        RefractionIndexSliderLabel   matlab.ui.control.Label
        ExtinctioncoefficientSlider  matlab.ui.control.Slider
        RefractionIndexSlider        matlab.ui.control.Slider
        ExtinctioncoefficientSliderLabel  matlab.ui.control.Label
        DisplayPanel                 matlab.ui.container.Panel
        PerspectiveDropDownLabel     matlab.ui.control.Label
        PerspectiveDropDown          matlab.ui.control.DropDown
        DurationsecEditField         matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_Wave1                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
         
        % function updateplot(app)
        %   % Perspective and Sampling rate and duration
        %     perspective = app.PerspectiveDropDown.Value;
        %     samplePerCycle = 100;
        %     duration = app.DurationsecEditField.Value;
        % 
        %     % Update labels
        %     app.LenLabel.Text = num2str(round(app.LengthSlider.Value));
        % 
        %     % Wave1
        %     fc1 = round(app.FrequencySlider.Value, 2);
        %     amp1 = round(app.AmplitudeSlider.Value, 2);
        %     fs1 = fc1 * samplePerCycle;
        % 
        %     t1 = 0:(1/fs1):(duration-1/fs1); % Time1
        %     S1 = amp1 * sin(2*pi*fc1*t1); % Signal1
        % 
        %     ylim(app.UIAxes_Wave1, [-10, 10]); % Wave1 Y-axis limits
        %     plot(app.UIAxes_Wave1, t1, S1, 'Color', 'red'); % Wave1 plot
        % 
        %     % Modulation -> Wave Traveling Through A Wave
        %     refractionIndex = app.RefractionIndexSlider.Value;
        %     extinctionCoefficient = app.ExtinctioncoefficientSlider.Value;
        %     modulationExtent = app.LengthSlider.Value / 100;
        % 
        %     % Modulation Logic
        %     center = duration / 2;
        %     half_extent = modulationExtent * duration / 0.8;
        %     start_modulation = center - half_extent;
        %     end_modulation = center + half_extent;
        % 
        %     mask = (t1 >= start_modulation) & (t1 <= end_modulation);
        %     modified_wavelength = (1 / fc1) / refractionIndex;
        %     new_time = t1 * (1 / fc1) / modified_wavelength;
        % 
        %     % Define the shaded region
        %     mod_region_x = [start_modulation, end_modulation, end_modulation, start_modulation];
        %     mod_region_y = [-10, -10, 10, 10];
        % 
        %     % Apply modulation and extinction coefficient
        %     S3 = S1 .* exp(-extinctionCoefficient * new_time);
        %     S3(mask) = S3(mask) ./ refractionIndex;
        % 
        %     % Ensure the wave after the modulation zone returns to its original amplitude
        %     S3(~mask) = S1(~mask);
        % 
        %     % Plot the modulated wave
        %     if (perspective == "3D")
        %         plot3(app.UIAxes, t1, S3, zeros(size(t1)), 'Color', 'blue');
        %     else % 2D
        %         plot(app.UIAxes, t1, S3, 'Color', 'blue');
        %         view(app.UIAxes, 2);
        %     end
        % 
        %     % Plot the shaded region
        %     hold(app.UIAxes, 'on');
        %     fill(app.UIAxes, mod_region_x, mod_region_y, 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
        %     hold(app.UIAxes, 'off');
        % 
        %     % Set Y-axis limits
        %     ylim(app.UIAxes, [-10, 10]);
        % end

        % function updateplot(app)
        %     % Perspective and Sampling rate and duration
        %     perspective = app.PerspectiveDropDown.Value;
        %     samplePerCycle = 100;
        %     duration = app.DurationsecEditField.Value;
        % 
        %     % Update labels
        %     app.LenLabel.Text = num2str(round(app.LengthSlider.Value));
        % 
        %     % Wave1
        %     fc1 = round(app.FrequencySlider.Value, 2);
        %     amp1 = round(app.AmplitudeSlider.Value, 2);
        %     fs1 = fc1 * samplePerCycle;
        % 
        %     t1 = 0:(1/fs1):(duration-1/fs1); % Time1
        %     S1 = amp1 * sin(2*pi*fc1*t1); % Signal1
        % 
        %     ylim(app.UIAxes_Wave1, [-10, 10]); % Wave1 Y-axis limits
        %     plot(app.UIAxes_Wave1, t1, S1, 'Color', 'red'); % Wave1 plot
        % 
        %     % Modulation -> Wave Traveling Through A Wave
        %     refractionIndex = app.RefractionIndexSlider.Value;
        %     extinctionCoefficient = app.ExtinctioncoefficientSlider.Value;
        %     modulationExtent = app.LengthSlider.Value / 100;
        % 
        %     % Modulation Logic
        %     center = duration / 2;
        %     half_extent = modulationExtent * duration / 0.8;
        %     start_modulation = center - half_extent;
        %     end_modulation = center + half_extent;
        % 
        %     mask = (t1 >= start_modulation) & (t1 <= end_modulation);
        %     modified_frequency = fc1 * refractionIndex;
        %     new_time = t1 * (1 / fc1) / refractionIndex;
        % 
        %     % Define the shaded region
        %     mod_region_x = [start_modulation, end_modulation, end_modulation, start_modulation];
        %     mod_region_y = [-10, -10, 10, 10];
        % 
        %     % Apply modulation and extinction coefficient
        %     S3 = S1 .* exp(-extinctionCoefficient * new_time);
        %     S3(mask) = amp1 * sin(2*pi*modified_frequency*(t1(mask) - start_modulation)); % Apply frequency modulation in the zone
        %     S3(t1 > end_modulation) = S3(t1 > end_modulation) .* exp(-extinctionCoefficient * (t1(t1 > end_modulation) - end_modulation)); % Apply extinction coefficient beyond the zone
        % 
        %     % Plot the modulated wave
        %     if (perspective == "3D")
        %         plot3(app.UIAxes, t1, S3, zeros(size(t1)), 'Color', 'blue');
        %     else % 2D
        %         plot(app.UIAxes, t1, S3, 'Color', 'blue');
        %         view(app.UIAxes, 2);
        %     end
        % 
        %     % Plot the shaded region
        %     hold(app.UIAxes, 'on');
        %     fill(app.UIAxes, mod_region_x, mod_region_y, 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
        %     hold(app.UIAxes, 'off');
        % 
        %     % Set Y-axis limits
        %     ylim(app.UIAxes, [-10, 10]);
        % end
        
        function updateplot(app)
            % Perspective and Sampling rate and duration
            perspective = app.PerspectiveDropDown.Value;
            samplePerCycle = 100;
            duration = app.DurationsecEditField.Value;
        
            % Update labels
            app.LenLabel.Text = num2str(round(app.LengthSlider.Value));
        
            % Wave1
            fc1 = round(app.FrequencySlider.Value, 2);
            amp1 = round(app.AmplitudeSlider.Value, 2);
            fs1 = fc1 * samplePerCycle;
        
            t1 = 0:(1/fs1):(duration-1/fs1); % Time1
            S1 = amp1 * sin(2*pi*fc1*t1); % Signal1
        
            ylim(app.UIAxes_Wave1, [-10, 10]); % Wave1 Y-axis limits
            plot(app.UIAxes_Wave1, t1, S1, 'Color', 'red'); % Wave1 plot
        
            % Modulation -> Wave Traveling Through A Wave
            refractionIndex = app.RefractionIndexSlider.Value;
            extinctionCoefficient = app.ExtinctioncoefficientSlider.Value;
            modulationExtent = app.LengthSlider.Value / 100;
        
            % Modulation Logic
            center = duration / 2;
            half_extent = modulationExtent * duration / 0.8;
            start_modulation = center - half_extent;
            end_modulation = center + half_extent;
        
            modified_wavelength = (1 / fc1) / refractionIndex;
            new_time = t1 * (1 / fc1) / refractionIndex;
        
            % Define the shaded region
            mod_region_x = [start_modulation, end_modulation, end_modulation, start_modulation];
            mod_region_y = [-10, -10, 10, 10];
        
            % Apply extinction coefficient
            S3 = S1 .* exp(-extinctionCoefficient * t1);
        
            % Apply frequency modulation in the zone
            modulated_fc1 = fc1 * refractionIndex;
            modulation_mask = (t1 >= start_modulation) & (t1 <= end_modulation);
            S3(modulation_mask) = amp1 * sin(2*pi*modulated_fc1*(t1(modulation_mask) - start_modulation));
            S3(modulation_mask) = S3(modulation_mask) .* exp(-extinctionCoefficient * (t1(modulation_mask) - start_modulation));
        
            % Plot the modulated wave
            if (perspective == "3D")
                plot3(app.UIAxes, t1, S3, zeros(size(t1)), 'Color', 'blue');
            else % 2D
                plot(app.UIAxes, t1, S3, 'Color', 'blue');
                view(app.UIAxes, 2);
            end
        
            % Plot the shaded region
            hold(app.UIAxes, 'on');
            fill(app.UIAxes, mod_region_x, mod_region_y, 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
            hold(app.UIAxes, 'off');
        
            % Set Y-axis limits
            ylim(app.UIAxes, [-10, 10]);
        end


        function resetLabels(app)
            app.AmpLabel.Text = num2str(round(app.AmplitudeSlider.Value));
            app.FreqLabel.Text = num2str(round(app.FrequencySlider.Value));
            app.LenLabel.Text = num2str(round(app.LengthSlider.Value));
        end

        function reset(app)
            app.AmplitudeSlider.Value = 5;
            app.FrequencySlider.Value = 4;
            app.LengthSlider.Value = 1;
            app.ExtinctioncoefficientSlider.Value = 0;
            app.RefractionIndexSlider.Value = 1;
            
            resetLabels(app);
            updateplot(app);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetLabels(app);
            updateplot(app)
        end

        % Callback function
        function SamplingRateEditFieldValueChanged(app, event)
            updateplot(app);  
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            app.FreqLabel.Text=num2str(round(app.FrequencySlider.Value));
            updateplot(app);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            app.AmpLabel.Text=num2str(round(app.AmplitudeSlider.Value));
            updateplot(app);
        end

        % Callback function
        function ResetWavesButtonPushed(app, event)
            reset(app);
        end

        % Value changed function: RefractionIndexSlider
        function RefractionIndexSliderValueChanged(app, event)
            app.RefraLabel.Text=num2str(round(app.RefractionIndexSlider.Value,1));
            updateplot(app);
        end

        % Value changed function: ExtinctioncoefficientSlider
        function ExtinctioncoefficientSliderValueChanged(app, event)
            app.ExtinLabel.Text=num2str(round(app.ExtinctioncoefficientSlider.Value,1));
            updateplot(app);
        end

        % Value changed function: LengthSlider
        function LengthSliderValueChanged(app, event)
            app.LenLabel.Text=num2str(round(app.LengthSlider.Value));
            updateplot(app);
        end

        % Button pushed function: ResetWavesButton_2
        function ResetWavesButton_2Pushed(app, event)
            reset(app);
        end

        % Value changed function: PerspectiveDropDown
        function PerspectiveDropDownValueChanged(app, event)
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Wave1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Wave1, 'Original Wave 1')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave 1 Through A Medium')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.DurationsecEditFieldLabel = uilabel(app.DisplayPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DurationsecEditField.Limits = [1 60];
            app.DurationsecEditField.Value = 2;
            app.PerspectiveDropDown = uidropdown(app.DisplayPanel);
            app.PerspectiveDropDown.Items = {'2D', '3D'};
            app.PerspectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @PerspectiveDropDownValueChanged, true);
            app.PerspectiveDropDown.Value = '2D';
            app.PerspectiveDropDownLabel = uilabel(app.DisplayPanel);
            app.PerspectiveDropDownLabel.Text = 'Perspective';
            app.WaveMedium = uipanel(app.UIFigure);
            app.WaveMedium.Title = 'Panel';
            app.ExtinctioncoefficientSliderLabel = uilabel(app.WaveMedium);
            app.ExtinctioncoefficientSliderLabel.Text = 'Extinction coefficient';
            app.RefractionIndexSlider = uislider(app.WaveMedium);
            app.RefractionIndexSlider.Limits = [1 2];
            app.RefractionIndexSlider.ValueChangedFcn = createCallbackFcn(app, @RefractionIndexSliderValueChanged, true);
            app.RefractionIndexSlider.Value = 1;
            app.ExtinctioncoefficientSlider = uislider(app.WaveMedium);
            app.ExtinctioncoefficientSlider.Limits = [0 1];
            app.ExtinctioncoefficientSlider.ValueChangedFcn = createCallbackFcn(app, @ExtinctioncoefficientSliderValueChanged, true);
            app.RefractionIndexSliderLabel = uilabel(app.WaveMedium);
            app.RefractionIndexSliderLabel.Text = 'Refraction Index';
            app.LengthSlider = uislider(app.WaveMedium);
            app.LengthSlider.Limits = [1 32];
            app.LengthSlider.ValueChangedFcn = createCallbackFcn(app, @LengthSliderValueChanged, true);
            app.LengthSlider.Value = 1;
            app.LengthSliderLabel = uilabel(app.WaveMedium);
            app.LengthSliderLabel.Text = 'Length';
            app.ExtinLabel = uilabel(app.WaveMedium);
            app.ExtinLabel.Text = 'Extin';
            app.RefraLabel = uilabel(app.WaveMedium);
            app.RefraLabel.Text = 'Refra';
            app.LenLabel = uilabel(app.WaveMedium);
            app.LenLabel.Text = 'Len';
            app.Wave1Panel = uipanel(app.UIFigure);
            app.Wave1Panel.Title = 'Wave 1';
            app.AmplitudeSliderLabel = uilabel(app.Wave1Panel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.Wave1Panel);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.MinorTicks = [1 3 5 7 9];
            app.AmplitudeSlider.Value = 5;
            app.FrequencySliderLabel = uilabel(app.Wave1Panel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.Wave1Panel);
            app.FrequencySlider.Limits = [1 9];
            app.FrequencySlider.MajorTicks = [1 3 5 7 9];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.MinorTicks = [2 4 6 8];
            app.FrequencySlider.Value = 4;
            app.AmpLabel = uilabel(app.Wave1Panel);
            app.AmpLabel.Text = 'Amp ';
            app.FreqLabel = uilabel(app.Wave1Panel);
            app.FreqLabel.Text = 'Freq';
            app.ResetWavesButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetWavesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButton_2Pushed, true);
            app.ResetWavesButton_2.Text = 'Waves ↺';
            app.ResetDisplayButton = uibutton(app.UIFigure, 'push');
            app.ResetDisplayButton.Text = 'Display ↺';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medium

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end