classdef voiceModulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResetWavesButton           matlab.ui.control.Button
        ModulationTechniquesPanel  matlab.ui.container.Panel
        pitchShiftSlider           matlab.ui.control.Slider
        SpeedLabel                 matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        NoiseLabel                 matlab.ui.control.Label
        NoiseSlider                matlab.ui.control.Slider
        RecordPanel                matlab.ui.container.Panel
        ResetAudioButton           matlab.ui.control.Button
        PlayAudioButton            matlab.ui.control.Button
        DeleteCurrentAudioButton   matlab.ui.control.Button
        RecordAudioButton          matlab.ui.control.Button
        UIAxes_Wave1               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RecordedAudioDataProperty % RecordedAudioData from the user
        ModulatedAudioData % Adjusted Audio from the user
        Fs = 44100;   % Sampling frequency 
        
    end
    
    methods (Access = private)
        

        function updateplot(app)

            if ~isempty(app.RecordedAudioDataProperty)                
                
        
                app.DeleteCurrentAudioButton.Enable = 'on'; 
                app.PlayAudioButton.Enable ="on";
                app.ResetAudioButton.Enable = "on";   

                app.ModulatedAudioData = app.RecordedAudioDataProperty;
                modAddNoise(app);
                modAjustSpeed(app);
                modPitchShift(app);
                
                t = (0:length(app.ModulatedAudioData)-1) / app.Fs;

                plot(app.UIAxes_Wave1, t, app.ModulatedAudioData, 'Color', 'blue');
                title(app.UIAxes_Wave1, 'Recorded Wave');
                xlabel(app.UIAxes_Wave1, 'Time (s)');
                ylabel(app.UIAxes_Wave1, 'Amplitude');
            else
                % If no recorded audio data, clear the plot
                cla(app.UIAxes_Wave1);
                title(app.UIAxes_Wave1, '');

                % Disable relevant buttons
                app.RecordAudioButton.Text = "Record Audio";
                app.PlayAudioButton.Text = "Play Audio";
                app.DeleteCurrentAudioButton.Enable = 'off'; 
                app.PlayAudioButton.Enable ="off";
                app.ResetAudioButton.Enable = "off";
            end
        end

        function recordAudio(app)
            % Specify audio parameters
            nBits = 16; % Number of bits
            nChannels = 1; % Number of channels (mono)
            recordingDuration = 5; % Specify recording duration (in seconds)
    
            % Create an audiorecorder object
            recObj = audiorecorder(app.Fs, nBits, nChannels);
    
            disp(['Start speaking. Recording for ', int2str(recordingDuration), ' sec'])
            app.RecordAudioButton.Text = "Start speaking";

            recordblocking(recObj, recordingDuration);
            app.RecordAudioButton.Text = "Record New Audio";
            disp('End of Recording');
    
            % Get the recorded audio data
            audioSampleData = getaudiodata(recObj);
            app.RecordedAudioDataProperty = audioSampleData;
            app.ModulatedAudioData = app.RecordedAudioDataProperty;
            
            % Disable relevant buttons
            updateplot(app);
        end
      
        function playAudio(app)
            if ~isempty(app.RecordedAudioDataProperty)

                audioPlayer = audioplayer(app.ModulatedAudioData, 44100); 
                
                % Play the audio
                disp('Playing audio...');
                app.PlayAudioButton.Text = "Playing audio...";
                playblocking(audioPlayer); 
                app.PlayAudioButton.Text = "Play Again";
                disp('Audio playback finished.');

            else
                errordlg('No recorded audio to play.', 'Error', 'modal');
                app.PlayAudioButton.Text = "No Audio To Play";
            end
        end

        function deleteAudio(app)
            % Delete the recorded audio data
            app.RecordedAudioDataProperty = [];
           
            % Update the plot to reflect the deletion
            updateplot(app);
        end
      
        function resetAudioMod(app)
            app.ModulatedAudioData = app.RecordedAudioDataProperty;
            updateplot(app);
        end


        function modAddNoise(app)

            % Check if there is recorded audio data
            if isempty(app.RecordedAudioDataProperty)
                % If no recorded data, display an error message
                return;
            end
        
            % Generate Gaussian noise with the same length as the recorded audio
            rng('default'); % Reset random number generator
            noiseMultiplier = round(app.NoiseSlider.Value,2)/100;
            app.NoiseLabel.Text = "Noise : " + num2str(noiseMultiplier*100);
            
            % Normalize the noise amplitude based on the maximum amplitude of the recorded audio
            maxAmplitude = max(abs(app.ModulatedAudioData));
            noise = (noiseMultiplier) * maxAmplitude * randn(size(app.ModulatedAudioData));
            
            % Add noise to the recorded audio
            app.ModulatedAudioData = app.ModulatedAudioData + noise;


        end

        function modAjustSpeed(app)
        
        if isempty(app.RecordedAudioDataProperty)
            % If no recorded data, display an error message
            return;
        end

        % Get the speed adjustment factor from the slider
        speedFactor = app.SpeedSlider.Value;
        app.SpeedLabel.Text = "Speed : " + num2str(round(speedFactor,2));
        if ( speedFactor > 0 )
            t_original = (0:length(app.ModulatedAudioData)-1) / app.Fs;
            t_new = linspace(0, t_original(end), round(length(app.ModulatedAudioData) / speedFactor));
        
            adjustedAudioData = interp1(t_original, app.ModulatedAudioData, t_new);
            app.ModulatedAudioData = adjustedAudioData;
        end
        
        
        end

        
       function modPitchShift(app)
            % Get the slider value
            sliderValue = app.pitchShiftSlider.Value;
            
            % Convert the slider value to a pitch shift factor
            if sliderValue >= 0
                pitchShiftFactor = 2^(sliderValue/12);  % Convert semitones to pitch factor for positive values
            else
                pitchShiftFactor = 1 / (2^(-sliderValue/12));  % Convert semitones to pitch factor for negative values
            end
            
            % Apply pitch shifting to the audio data if pitchShiftFactor is not equal to 1
            if pitchShiftFactor ~= 1
                % Define the analysis parameters
                frameLength = 1024;  % Length of each analysis frame
                overlapRatio = 0.75; % Overlap ratio between frames
                
                % Calculate the hop size (number of samples to move to the next frame)
                hopSize = round(frameLength * (1 - overlapRatio));
                
                % Initialize the output audio data
                adjustedAudioData = zeros(size(app.ModulatedAudioData));
                
                % Perform pitch shifting using PSOLA
                currentIndex = 1;
                while currentIndex + frameLength <= length(app.ModulatedAudioData)
                    % Extract the current frame
                    frame = app.ModulatedAudioData(currentIndex:currentIndex+frameLength-1);
                    
                    % Compute the pitch-shifted frame size
                    shiftedFrameLength = round(frameLength / pitchShiftFactor);
                                
                    % Ensure that the shifted frame length does not exceed the available space in adjustedAudioData
                if currentIndex + shiftedFrameLength - 1 > length(adjustedAudioData)
                    shiftedFrameLength = length(adjustedAudioData) - currentIndex + 1;
                end
                    % Interpolate the frame using linear interpolation
                    shiftedFrame = interp1(1:frameLength, frame, linspace(1, frameLength, shiftedFrameLength), 'linear');
                    
                    % Update the output audio data
                    adjustedAudioData(currentIndex:currentIndex+shiftedFrameLength-1) = adjustedAudioData(currentIndex:currentIndex+shiftedFrameLength-1) + shiftedFrame;
                    
                    % Move to the next frame
                    currentIndex = currentIndex + hopSize;
                end
                
                % Update the modulated audio data
                app.ModulatedAudioData = adjustedAudioData;
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable relevant buttons
            app.DeleteCurrentAudioButton.Enable = 'off'; 
            app.PlayAudioButton.Enable ="off";
            
            app.ResetAudioButton.Enable = "off";

        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            recordAudio(app);
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            playAudio(app);
        end

        % Button pushed function: DeleteCurrentAudioButton
        function DeleteCurrentAudioButtonPushed(app, event)
            deleteAudio(app);
        end

        % Button pushed function: ResetWavesButton
        function ResetWavesButtonPushed(app, event)
            deleteAudio(app);
        end

        % Button pushed function: ResetAudioButton
        function ResetAudioButtonPushed(app, event)
            resetAudioMod(app);
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: pitchShiftSlider
        function pitchShiftSliderValueChanged(app, event)
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Wave1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Wave1, 'Recorded Wave')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            app.RecordPanel = uipanel(app.UIFigure);
            app.RecordPanel.Title = 'Record';
            app.RecordAudioButton = uibutton(app.RecordPanel, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.DeleteCurrentAudioButton = uibutton(app.RecordPanel, 'push');
            app.DeleteCurrentAudioButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentAudioButtonPushed, true);
            app.DeleteCurrentAudioButton.Text = 'Delete Current Audio';
            app.PlayAudioButton = uibutton(app.RecordPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.ResetAudioButton = uibutton(app.RecordPanel, 'push');
            app.ResetAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAudioButtonPushed, true);
            app.ResetAudioButton.Text = 'Reset Audio';
            app.ModulationTechniquesPanel = uipanel(app.UIFigure);
            app.ModulationTechniquesPanel.Title = 'Modulation Techniques';
            app.NoiseSlider = uislider(app.ModulationTechniquesPanel);
            app.NoiseSlider.Limits = [0 4];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.NoiseLabel = uilabel(app.ModulationTechniquesPanel);
            app.NoiseLabel.Text = 'Noise : ';
            app.SpeedSlider = uislider(app.ModulationTechniquesPanel);
            app.SpeedSlider.Limits = [0 4];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 1;
            app.SpeedLabel = uilabel(app.ModulationTechniquesPanel);
            app.SpeedLabel.Text = 'Speed :';
            app.pitchShiftSlider = uislider(app.ModulationTechniquesPanel);
            app.pitchShiftSlider.Limits = [-2 2];
            app.pitchShiftSlider.ValueChangedFcn = createCallbackFcn(app, @pitchShiftSliderValueChanged, true);
            app.ResetWavesButton = uibutton(app.UIFigure, 'push');
            app.ResetWavesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButtonPushed, true);
            app.ResetWavesButton.Text = 'Waves ↺';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceModulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end